classdef Tugas_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        ExecuteButton         matlab.ui.control.Button
        StandartDeviasiLabel  matlab.ui.control.Label
        MeanLabel             matlab.ui.control.Label
        SkewnessLabel         matlab.ui.control.Label
        PowerLabel            matlab.ui.control.Label
        EntropyLabel          matlab.ui.control.Label
        EditField_skewness    matlab.ui.control.NumericEditField
        EditField_power       matlab.ui.control.NumericEditField
        EditField_mean        matlab.ui.control.NumericEditField
        EditField_entropy     matlab.ui.control.NumericEditField
        EditField_kurtosis    matlab.ui.control.NumericEditField
        EditField_sd          matlab.ui.control.NumericEditField
        PembagiLabel          matlab.ui.control.Label
        EditField_pembagi     matlab.ui.control.NumericEditField
        KurtosisLabel         matlab.ui.control.Label
        RangeminLabel         matlab.ui.control.Label
        EditField_rangemin    matlab.ui.control.NumericEditField
        RangemaxLabel         matlab.ui.control.Label
        EditField_rangemax    matlab.ui.control.NumericEditField
        LoadDataButton        matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        VariableLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        divider; % Description
        aa;
        data;
    end
    

    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            %load('E:\Berkas Banin\KULIAH\PSD\GUI-Matlab\MATLAB\0505\data_filter.mat');
            
            i = 1;
            j = size(app.data, 2) - (app.divider - 1);
            for k = 1:app.divider:j
                ax(1, i) = sum(app.data(1, k:k+(app.divider - 1))) / app.divider;
                ax(2, i) = sum(app.data(2, k:k+(app.divider - 1))) / app.divider;
                i = i + 1;  
            end
            plot(app.UIAxes, ax(app.EditField_rangemin.Value : app.EditField_rangemax.Value))
            app.EditField_kurtosis.Value = kurtosis(ax(app.EditField_rangemin.Value : app.EditField_rangemax.Value));
            app.EditField_mean.Value = mean(ax(app.EditField_rangemin.Value : app.EditField_rangemax.Value));
            app.EditField_sd.Value = std(ax(app.EditField_rangemin.Value : app.EditField_rangemax.Value));
            app.EditField_skewness.Value = skewness(ax(app.EditField_rangemin.Value : app.EditField_rangemax.Value));
        end

        % Value changed function: EditField_pembagi
        function EditField_pembagiValueChanged(app, event)
            app.divider = app.EditField_pembagi.Value;
        end

        % Value changed function: EditField_rangemin
        function EditField_rangeminValueChanged(app, event)
            value = app.EditField_rangemin.Value;
            app.EditField_rangemax.Limits = [value + 1, inf];
        end

        % Value changed function: EditField_rangemax
        function EditField_rangemaxValueChanged(app, event)
            value = app.EditField_rangemax.Value;
            app.EditField_rangemin.Limits = [1, value];
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename pathname] = uigetfile({'*.mat'}, 'File Selector');
            
            cd(pathname);
            app.aa = load(filename);
            list = evalin('base', 'who');
            app.Label.Text = list;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.data = evalin('base', value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.StandartDeviasiLabel = uilabel(app.UIFigure);
            app.StandartDeviasiLabel.Text = 'Standart Deviasi : ';
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'Mean : ';
            app.SkewnessLabel = uilabel(app.UIFigure);
            app.SkewnessLabel.Text = 'Skewness : ';
            app.PowerLabel = uilabel(app.UIFigure);
            app.PowerLabel.Text = 'Power : ';
            app.EntropyLabel = uilabel(app.UIFigure);
            app.EntropyLabel.Text = 'Entropy : ';
            app.PembagiLabel = uilabel(app.UIFigure);
            app.PembagiLabel.Text = 'Pembagi : ';
            app.EditField_pembagi = uieditfield(app.UIFigure, 'numeric');
            app.EditField_pembagi.Limits = [1 Inf];
            app.EditField_pembagi.ValueChangedFcn = createCallbackFcn(app, @EditField_pembagiValueChanged, true);
            app.EditField_pembagi.Value = 1;
            app.KurtosisLabel = uilabel(app.UIFigure);
            app.KurtosisLabel.Text = 'Kurtosis : ';
            app.RangeminLabel = uilabel(app.UIFigure);
            app.RangeminLabel.Text = 'Range min :';
            app.EditField_rangemin = uieditfield(app.UIFigure, 'numeric');
            app.EditField_rangemin.Limits = [1 Inf];
            app.EditField_rangemin.ValueChangedFcn = createCallbackFcn(app, @EditField_rangeminValueChanged, true);
            app.EditField_rangemin.Value = 1;
            app.EditField_sd = uieditfield(app.UIFigure, 'numeric');
            app.EditField_skewness = uieditfield(app.UIFigure, 'numeric');
            app.EditField_power = uieditfield(app.UIFigure, 'numeric');
            app.EditField_mean = uieditfield(app.UIFigure, 'numeric');
            app.EditField_entropy = uieditfield(app.UIFigure, 'numeric');
            app.EditField_kurtosis = uieditfield(app.UIFigure, 'numeric');
            app.EditField_rangemax = uieditfield(app.UIFigure, 'numeric');
            app.EditField_rangemax.ValueChangedFcn = createCallbackFcn(app, @EditField_rangemaxValueChanged, true);
            app.RangemaxLabel = uilabel(app.UIFigure);
            app.RangemaxLabel.Text = 'Range max :';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.VariableLabel = uilabel(app.UIFigure);
            app.VariableLabel.Text = 'Variable : ';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Tugas_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end